classdef SubtitleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ExportsrtButton   matlab.ui.control.Button
        LogListBox        matlab.ui.control.ListBox
        LogListBoxLabel   matlab.ui.control.Label
        EveryNField       matlab.ui.control.NumericEditField
        EveryNFieldLabel  matlab.ui.control.Label
        GeomCheckBox      matlab.ui.control.CheckBox
        BoxesCheckBox     matlab.ui.control.CheckBox
        NCCCheckBox       matlab.ui.control.CheckBox
        MSERCheckBox      matlab.ui.control.CheckBox
        StopButton        matlab.ui.control.Button
        PlayButton        matlab.ui.control.Button
        CameraButton      matlab.ui.control.Button
        OpenButton        matlab.ui.control.Button
        StatusLabel       matlab.ui.control.Label
        VideoAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Video            % VideoReader object for the open file
        FrameIdx = 0     % how many frames have been read so far
        Running = false  % true while the Play loop is going
        Templates        % NCC reference images (A-Z, 0-9), built once
        Labels           % the character each template represents
        Segments = struct('t0', {}, 't1', {}, 'text', {})  % subtitles: start, end, text
        LastText = ''    % last text added, to avoid repeats
        History = {}     % texts from the last 3 pipeline runs

    end

    
    methods (Access = private)
        
        function [boxes, texts] = runPipeline(app, small, full)
            % 1. Detection (Mazen) on the small frame -> fast
            boxes = detectTextRegions(small);
            n = size(boxes, 1);
            texts = cell(1, n);
            if n == 0, return; end

            % Same boxes in full-resolution coordinates
            s = size(full, 2) / size(small, 2);
            bigBoxes = boxes * s;

            % 2. Geometric correction (Huzayfa) on the FULL frame. Fallback: plain crops.
            [H, W, ~] = size(full);
            rois = cell(n, 1);
            for k = 1:n
                b = round(bigBoxes(k, :));
                x1 = max(1, b(1));  x2 = min(W, b(1) + b(3));
                y1 = max(1, b(2));  y2 = min(H, b(2) + b(4));
                rois{k} = full(y1:y2, x1:x2, :);
            end
            if app.GeomCheckBox.Value
                try
                    rois = GeometricCorrection(full, bigBoxes);
                catch err
                    warning('GeometricCorrection failed: %s', err.message);
                end
            end

            % 3. Recognition: OCR + NCC (Othman). Fallback: plain OCR.
            for k = 1:n
                if isempty(rois{k}), texts{k} = ''; continue; end
                roi = padarray(rois{k}, [20 20], 'replicate');   % margin around text
                if app.NCCCheckBox.Value && ~isempty(app.Templates)
                    R = recognize_text_region(roi, app.Templates, app.Labels);
                    texts{k} = R.correctedText;
                else
                    res = ocr(roi, LayoutAnalysis="line");
                    words = res.Words(res.WordConfidences >= 0.6);   % keep confident words
                    txt = strjoin(words', ' ');
                    txt = regexprep(txt, '[^A-Za-z0-9 \-\.:]', '');  % drop odd symbols
                    if numel(regexprep(txt, '\s', '')) < 2, txt = ''; end
                    texts{k} = strtrim(txt);
                end
            end

            % Drop boxes where no valid text was read (likely false detections)
            keep = ~cellfun(@isempty, texts);
            boxes = boxes(keep, :);
            texts = texts(keep);

            % Reading order: top-to-bottom, then left-to-right
            [~, order] = sortrows([round(boxes(:, 2) / 20), boxes(:, 1)]);
            boxes = boxes(order, :);
            texts = texts(order);
        end

        function addToLog(app, t, txt, step)
            % t    = time of this frame (s)
            % txt  = all text found in this frame, joined into one string
            % step = seconds until the next pipeline run (N / fps)
            if isempty(txt)
                app.LastText = '';            % nothing found -> next text starts fresh
                return;
            end
            if strcmp(txt, app.LastText)
                app.Segments(end).t1 = t + step;   % same text still on screen -> extend
                return;
            end
            % New text: start a new subtitle and add a line to the list box
            app.Segments(end+1) = struct('t0', t, 't1', t + step, 'text', txt);
            app.LastText = txt;
            app.LogListBox.Items{end+1} = sprintf('%02d:%04.1f  %s', floor(t/60), mod(t, 60), txt);
            scroll(app.LogListBox, 'bottom');
        end

        
        function stable = stableTexts(app, texts)
            % Keep only text seen in at least 2 of the last 3 pipeline runs
            app.History{end+1} = texts;
            if numel(app.History) > 3
                app.History(1) = [];              % forget the oldest run
            end
            stable = {};
            for h = numel(app.History):-1:1       % newest run first (keeps reading order)
                for j = 1:numel(app.History{h})
                    item = app.History{h}{j};
                    runsWithItem = sum(cellfun(@(run) any(strcmp(run, item)), app.History));
                    if runsWithItem >= 2 && ~any(strcmp(stable, item))
                        stable{end+1} = item;
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Build the NCC templates once. Fails until render_char_image.m is pushed,
            % then the app uses plain OCR instead.
            try
                [app.Templates, app.Labels] = make_char_templates();
            catch err
                warning('NCC templates not available: %s', err.message);
            end

        end

        % Button pushed function: ExportsrtButton
        function ExportsrtButtonPushed(app, event)
            if isempty(app.Segments)
                uialert(app.UIFigure, 'Nothing extracted yet. Play a video first.', 'Nothing to export');
                return;
            end

            [~, base] = fileparts(app.Video.Name);              % "V03.MOV" -> "V03"
            [file, folder] = uiputfile('*.srt', 'Save subtitles', [base '.srt']);
            figure(app.UIFigure);
            if isequal(file, 0), return; end                    % user pressed Cancel

            % milliseconds -> "hh:mm:ss,mmm"
            srtTime = @(ms) sprintf('%02d:%02d:%02d,%03d', floor(ms/3600000), ...
                mod(floor(ms/60000), 60), mod(floor(ms/1000), 60), mod(ms, 1000));

            fid = fopen(fullfile(folder, file), 'w');
            for k = 1:numel(app.Segments)
                s = app.Segments(k);
                fprintf(fid, '%d\n%s --> %s\n%s\n\n', k, ...
                    srtTime(round(s.t0 * 1000)), srtTime(round(s.t1 * 1000)), s.text);
            end
            fclose(fid);

            uialert(app.UIFigure, sprintf('Saved %d subtitles to %s', numel(app.Segments), file), ...
                'Export done', 'Icon', 'success');

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.Video)
                uialert(app.UIFigure, 'Open a video first.', 'No video');
                return;
            end
            if app.Running, return; end           % already playing
            app.Running = true;

            hImg = [];                            % image object, created on first frame
            tStart = tic;
            shown = 0;
            boxes = zeros(0, 4);                  % last pipeline result, reused between runs
            texts = {};
            stable = {};                          % filtered text for subtitle + log
            procMs = 0;                           % how long the pipeline took (ms)

            while isvalid(app) && app.Running && hasFrame(app.Video)
                full = readFrame(app.Video);
                app.FrameIdx = app.FrameIdx + 1;
                t = app.Video.CurrentTime;
                work  = imresize(full, 1920 / max(size(full, 1:2)));   % correction + OCR
                frame = imresize(work, 0.5);                           % display + detection
                clear full                                             % free the 4K frame

                % Run the pipeline only on every N-th frame
                if mod(app.FrameIdx - 1, app.EveryNField.Value) == 0
                    tp = tic;
                    [boxes, texts] = runPipeline(app, frame, work);
                    procMs = toc(tp) * 1000;
                    stable = stableTexts(app, texts);
                    txt = strjoin(stable, ' | ');
                    addToLog(app, t, txt, app.EveryNField.Value / app.Video.FrameRate);
                end

                % Draw boxes + labels and the subtitle onto a copy of the frame
                shownFrame = frame;
                if app.BoxesCheckBox.Value && ~isempty(boxes)
                    labels = texts;
                    labels(cellfun(@isempty, labels)) = {'?'};
                    shownFrame = insertObjectAnnotation(shownFrame, 'rectangle', boxes, labels, ...
                        'Color', 'green', 'FontSize', 14);
                end
                subtitle = strjoin(stable, '   ');
                if ~isempty(subtitle)
                    shownFrame = insertText(shownFrame, [size(frame, 2)/2, size(frame, 1) - 20], ...
                        subtitle, 'AnchorPoint', 'CenterBottom', 'FontSize', 22, ...
                        'BoxColor', 'black', 'BoxOpacity', 0.7, 'TextColor', 'white');
                end

                if isempty(hImg)
                    hImg = imshow(shownFrame, 'Parent', app.VideoAxes);
                else
                    hImg.CData = shownFrame;      % swap pixels, much faster than imshow
                end

                shown = shown + 1;
                app.StatusLabel.Text = sprintf('Frame %d | t = %.1f s | %.1f fps | pipeline %.0f ms', ...
                    app.FrameIdx, t, shown / toc(tStart), procMs);
                drawnow limitrate;                % redraw + let Stop button run
            end

            if isvalid(app), app.Running = false; end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Running = false;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file, folder] = uigetfile({'*.mov;*.MOV;*.mp4;*.avi', 'Video files'});
            figure(app.UIFigure);                 % bring the app back to the front
            if isequal(file, 0), return; end      % user pressed Cancel

            app.Video = VideoReader(fullfile(folder, file));
            frame = readFrame(app.Video);
            app.FrameIdx = 1;

            app.Segments = struct('t0', {}, 't1', {}, 'text', {});
            app.LastText = '';
            app.LogListBox.Items = {};
            app.History = {};


            scale = 960 / max(size(frame, 1:2));  % longest side -> 960 px
            frame = imresize(frame, scale);
            imshow(frame, 'Parent', app.VideoAxes);

            app.StatusLabel.Text = sprintf('%s | %dx%d | %.0f fps | %.1f s', file, ...
                app.Video.Width, app.Video.Height, app.Video.FrameRate, app.Video.Duration);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Group 7 - Video Text Subtitling';
            app.VideoAxes = uiaxes(app.UIFigure);
            zlabel(app.VideoAxes, 'Z')
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'No video Loaded.';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open Video';
            app.CameraButton = uibutton(app.UIFigure, 'push');
            app.CameraButton.Text = 'Open Camera';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MSERCheckBox = uicheckbox(app.UIFigure);
            app.MSERCheckBox.Text = 'MSER detection';
            app.MSERCheckBox.Value = true;
            app.NCCCheckBox = uicheckbox(app.UIFigure);
            app.NCCCheckBox.Text = 'NCC verification';
            app.NCCCheckBox.Value = true;
            app.BoxesCheckBox = uicheckbox(app.UIFigure);
            app.BoxesCheckBox.Text = 'Show Boxes';
            app.BoxesCheckBox.Value = true;
            app.GeomCheckBox = uicheckbox(app.UIFigure);
            app.GeomCheckBox.Text = 'Geometric correction';
            app.GeomCheckBox.Value = true;
            app.EveryNFieldLabel = uilabel(app.UIFigure);
            app.EveryNFieldLabel.Text = 'Process every N frames';
            app.EveryNField = uieditfield(app.UIFigure, 'numeric');
            app.EveryNField.Limits = [1 Inf];
            app.EveryNField.RoundFractionalValues = 'on';
            app.EveryNField.Value = 5;
            app.LogListBoxLabel = uilabel(app.UIFigure);
            app.LogListBoxLabel.Text = 'Extracted text';
            app.LogListBox = uilistbox(app.UIFigure);
            app.LogListBox.Items = {''};
            app.LogListBox.Value = '';
            app.ExportsrtButton = uibutton(app.UIFigure, 'push');
            app.ExportsrtButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsrtButtonPushed, true);
            app.ExportsrtButton.Text = 'Export .srt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubtitleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end